classdef CarDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        carDetection       matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        CarDetectionLabel  matlab.ui.control.Label
        Image5             matlab.ui.control.Image
        openVideo          matlab.ui.control.Image
        GridLayout4        matlab.ui.container.GridLayout
        videoPanel         matlab.ui.control.UIAxes
        imagePanel         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.carDetection.WindowState = 'maximized';
        end

        % Callback function
        function ImageClicked(app, event)
            close(app.carDetection);
        end

        % Callback function
        function Image8Clicked(app, event)
            close(app.carDetection);
            SmartCities;
        end

        % Callback function
        function ThingSpeakWebImageClicked(app, event)
            web https://thingspeak.com/channels/880528 -browser
        end

        % Callback function
        function QueriesImageClicked(app, event)
            winopen(fullfile(pwd,"Vehiculos"));
        end

        % Image clicked function: openVideo
        function openVideoImageClicked(app, event)
            [videoName, directory]=uigetfile({'*.*','All files'});
            CarDetectionFunction(directory, videoName, app.videoPanel, app.imagePanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.carDetection = uifigure('Visible', 'off');
            app.carDetection.Name = 'Smart Cities';
            app.Panel = uipanel(app.carDetection);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CarDetectionLabel = uilabel(app.GridLayout3);
            app.CarDetectionLabel.Text = 'Car Detection';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'car.png';
            app.openVideo = uiimage(app.GridLayout);
            app.openVideo.ImageClickedFcn = createCallbackFcn(app, @openVideoImageClicked, true);
            app.openVideo.ImageSource = 'document-folder-open-video-512.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.videoPanel = uiaxes(app.GridLayout4);
            title(app.videoPanel, '')
            xlabel(app.videoPanel, '')
            ylabel(app.videoPanel, '')
            app.videoPanel.XTick = [];
            app.videoPanel.YTick = [];
            app.imagePanel = uiaxes(app.GridLayout4);
            title(app.imagePanel, '')
            xlabel(app.imagePanel, '')
            ylabel(app.imagePanel, '')
            app.imagePanel.XTick = [];
            app.imagePanel.XTickLabel = '';
            app.imagePanel.YTick = [];
            app.imagePanel.YTickLabel = '';
            % Show the figure after all components are created
            app.carDetection.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.carDetection)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.carDetection)
        end
    end
end